classdef Exporter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ButtonExport                    matlab.ui.control.Image
        CheckBoxConvertInterfacesToSignals  matlab.ui.control.CheckBox
        CheckBoxUseExternalUIDs         matlab.ui.control.CheckBox
        OutputDirectoryLabel            matlab.ui.control.Label
        ButtonBrowseOutput              matlab.ui.control.Button
        EditFieldOutputDirectory        matlab.ui.control.EditField
        SourceSystemComposerModelLabel  matlab.ui.control.Label
        ButtonBrowseInput               matlab.ui.control.Button
        EditFieldInputFile              matlab.ui.control.EditField
        LabelImport                     matlab.ui.control.Label
        ButtonCleanEnvironment          matlab.ui.control.Image
        ButtonLauncher                  matlab.ui.control.Image
        ButtonHelp                      matlab.ui.control.Image
        ConnectorLabel                  matlab.ui.control.Label
        SysMLLabel                      matlab.ui.control.Label
        SystemComposerLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        InputFile % File for import
        OutputDirectory % Directory for imported project.
    end
    
    methods        
        function set.InputFile(app, filePath)
            app.InputFile = filePath;
            [~, filePath, fileExt] = fileparts(filePath);

            app.EditFieldInputFile.Value = [filePath fileExt]; %#ok<MCSUP>
            app.ButtonExport.Enable = 'on'; %#ok<MCSUP>
        end

        function set.OutputDirectory(app, directory)
            app.OutputDirectory = directory;
            app.EditFieldOutputDirectory.Value = directory; %#ok<MCSUP>
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, options)
            arguments
                app
                options.ReferencePosition = []
            end

            if ~isempty(options.ReferencePosition)
                % Center the app on the provided reference position.
                app.UIFigure.Position = SysMLConnector.internal.utils.getCenterPosition(options.ReferencePosition, app.UIFigure.Position);
            end

            iconPath = fullfile(fileparts(fileparts(mfilename('fullpath'))), '+icons');
            app.UIFigure.Icon              = fullfile(iconPath, 'ico-sysml-connector-app-16.png');
            app.ButtonExport.ImageSource   = fullfile(iconPath, 'ico-sysml-connector-export-button.png');
            app.ButtonLauncher.ImageSource = fullfile(iconPath, 'ico-sysml-connector-launcher.png');
            app.ButtonHelp.ImageSource     = fullfile(iconPath, 'ico-sysml-connector-help.svg');
            app.ButtonCleanEnvironment.ImageSource = fullfile(iconPath, 'ico-sysml-connector-clean.png');
        end

        % Image clicked function: ButtonHelp
        function ButtonHelpImageClicked(app, event)
            SysMLConnector.internal.doc('Userguide_Scr.html');
        end

        % Image clicked function: ButtonLauncher
        function ButtonLauncherImageClicked(app, event)
            SysMLConnector.internal.app.Launcher(ReferencePosition=app.UIFigure.Position);
            delete(app);
        end

        % Image clicked function: ButtonCleanEnvironment
        function ButtonCleanEnvironmentImageClicked(app, event)
            selection = uiconfirm(app.UIFigure, 'Cleaning the environment may result in loss of any unsaved artifacts, including models, dictionaries, and workspace variables. Are you sure you want to clean the environment?', 'Confirm Clean Environment');

            if strcmp(selection, 'OK')
                d = uiprogressdlg(app.UIFigure, Title='Please Wait', Message='Cleaning MATLAB Environment', Indeterminate='on');
                evalin('base', 'SysMLConnector.cleanEnvironment');
                close(d);
            end
        end

        % Button pushed function: ButtonBrowseInput
        function ButtonBrowseInputPushed(app, event)
            [file, location] = uigetfile({'*.slx', 'System Composer Model (*.slx)'}, ...
                                          'Select File to Export');

            if ~isequal(file, 0)
                app.InputFile = fullfile(location, file);
            end

            SysMLConnector.internal.utils.toFront(app);
        end

        % Button pushed function: ButtonBrowseOutput
        function ButtonBrowseOutputPushed(app, event)
            directory = uigetdir(pwd, 'Select Output Directory for SysML Model');

            if ~isequal(directory, 0)
                app.OutputDirectory = directory;
            end

            SysMLConnector.internal.utils.toFront(app);
        end

        % Image clicked function: ButtonExport
        function ButtonExportImageClicked(app, event)
            exportStart = tic;

            useExternalUIDs = app.CheckBoxUseExternalUIDs.Value;
            makeNestedInterfacesSignals = app.CheckBoxConvertInterfacesToSignals.Value;

            [~, outputFileName] = fileparts(app.InputFile);
            outputFile = fullfile(app.OutputDirectory, [outputFileName '.xml']);
            
            modelWasLoaded = bdIsLoaded(outputFileName);

            d = uiprogressdlg(app.UIFigure, Title='Please Wait', Message=sprintf('Exporting %s', app.EditFieldInputFile.Value), Indeterminate='on');
            ME = MException.empty;
            try
                if isempty(which(app.InputFile))
                    error('The specified model is not on the MATLAB path. Please add the file and dependencies to the path then try again');
                end
                
                inputModel = systemcomposer.loadModel(app.InputFile);
                SysMLConnector.export(inputModel, outputFile, UseExternalUIDs=useExternalUIDs, MakeNestedInterfacesSignals=makeNestedInterfacesSignals);
            catch ME
            end

            % If the model was opened by the exporter, close the model.
            if ~modelWasLoaded && bdIsLoaded(outputFileName)
                bdclose(outputFileName);
            end

            close(d);
            
            elapsedTime = seconds(toc(exportStart));
            elapsedTime.Format = 'hh:mm:ss';

            SysMLConnector.internal.utils.toFront(app);

            if ~isempty(ME)
                if isempty(ME.identifier)
                    errorStr = ME.message;
                else
                    errorStr = ME.identifier;
                end
                uialert(app.UIFigure, sprintf('Export did not complete successfully. Failed after %s with error: %s.', elapsedTime, errorStr), 'Export Failed', Icon='error');
            else
                selection = uiconfirm(app.UIFigure, sprintf('Export completed successfully in %s.', elapsedTime), 'Export Complete', Icon='success', Options={'OK', 'Show XMI'});

                if strcmp(selection, 'Show XMI')
                    open(outputFile);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'System Composer SysML Connector';
            app.SystemComposerLabel = uilabel(app.UIFigure);
            app.SystemComposerLabel.Text = 'System Composer';
            app.SysMLLabel = uilabel(app.UIFigure);
            app.SysMLLabel.Text = 'SysML';
            app.ConnectorLabel = uilabel(app.UIFigure);
            app.ConnectorLabel.Text = 'Connector';
            app.ButtonHelp = uiimage(app.UIFigure);
            app.ButtonHelp.ImageClickedFcn = createCallbackFcn(app, @ButtonHelpImageClicked, true);
            app.ButtonLauncher = uiimage(app.UIFigure);
            app.ButtonLauncher.ImageClickedFcn = createCallbackFcn(app, @ButtonLauncherImageClicked, true);
            app.ButtonCleanEnvironment = uiimage(app.UIFigure);
            app.ButtonCleanEnvironment.ImageClickedFcn = createCallbackFcn(app, @ButtonCleanEnvironmentImageClicked, true);
            app.LabelImport = uilabel(app.UIFigure);
            app.LabelImport.Text = 'Export';
            app.EditFieldInputFile = uieditfield(app.UIFigure, 'text');
            app.EditFieldInputFile.Editable = 'off';
            app.ButtonBrowseInput = uibutton(app.UIFigure, 'push');
            app.ButtonBrowseInput.ButtonPushedFcn = createCallbackFcn(app, @ButtonBrowseInputPushed, true);
            app.ButtonBrowseInput.Text = 'Browse';
            app.SourceSystemComposerModelLabel = uilabel(app.UIFigure);
            app.SourceSystemComposerModelLabel.Text = 'Source System Composer Model';
            app.EditFieldOutputDirectory = uieditfield(app.UIFigure, 'text');
            app.EditFieldOutputDirectory.Editable = 'off';
            app.ButtonBrowseOutput = uibutton(app.UIFigure, 'push');
            app.ButtonBrowseOutput.ButtonPushedFcn = createCallbackFcn(app, @ButtonBrowseOutputPushed, true);
            app.ButtonBrowseOutput.Text = 'Browse';
            app.OutputDirectoryLabel = uilabel(app.UIFigure);
            app.OutputDirectoryLabel.Text = 'Output Directory';
            app.CheckBoxUseExternalUIDs = uicheckbox(app.UIFigure);
            app.CheckBoxUseExternalUIDs.Text = 'Use External UIDs';
            app.CheckBoxUseExternalUIDs.Value = true;
            app.CheckBoxConvertInterfacesToSignals = uicheckbox(app.UIFigure);
            app.CheckBoxConvertInterfacesToSignals.Text = 'Convert Nested Interfaces to Signals';
            app.ButtonExport = uiimage(app.UIFigure);
            app.ButtonExport.ImageClickedFcn = createCallbackFcn(app, @ButtonExportImageClicked, true);
            app.ButtonExport.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Exporter(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end