classdef Launcher < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ButtonHelp           matlab.ui.control.Image
        ButtonImport         matlab.ui.control.Image
        ButtonExport         matlab.ui.control.Image
        ConnectorLabel       matlab.ui.control.Label
        SysMLLabel           matlab.ui.control.Label
        SystemComposerLabel  matlab.ui.control.Label
    end

    % Properties that correspond to app components

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, options)
            arguments
                app
                options.ReferencePosition = get(0, "DefaultFigurePosition")
            end

            % Center the app on the provided reference position.
            app.UIFigure.Position = SysMLConnector.internal.utils.getCenterPosition(options.ReferencePosition, app.UIFigure.Position);

            iconPath = fullfile(fileparts(fileparts(mfilename('fullpath'))), '+icons');
            app.UIFigure.Icon            = fullfile(iconPath, 'ico-sysml-connector-app-16.png');
            app.ButtonImport.ImageSource = fullfile(iconPath, 'ico-sysml-connector-import-button.png');
            app.ButtonExport.ImageSource = fullfile(iconPath, 'ico-sysml-connector-export-button.png');
            app.ButtonHelp.ImageSource   = fullfile(iconPath, 'ico-sysml-connector-help.svg');
        end

        % Image clicked function: ButtonHelp
        function ButtonHelpImageClicked(app, event)
            SysMLConnector.internal.doc('Userguide_Scr.html');
        end

        % Image clicked function: ButtonImport
        function ButtonImportImageClicked(app, event)
            SysMLConnector.internal.app.Importer(ReferencePosition=app.UIFigure.Position);
            delete(app);
        end

        % Image clicked function: ButtonExport
        function ButtonExportImageClicked(app, event)
            SysMLConnector.internal.app.Exporter(ReferencePosition=app.UIFigure.Position);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'System Composer SysML Connector';
            app.SystemComposerLabel = uilabel(app.UIFigure);
            app.SystemComposerLabel.Text = 'System Composer';
            app.SysMLLabel = uilabel(app.UIFigure);
            app.SysMLLabel.Text = 'SysML';
            app.ConnectorLabel = uilabel(app.UIFigure);
            app.ConnectorLabel.Text = 'Connector';
            app.ButtonExport = uiimage(app.UIFigure);
            app.ButtonExport.ImageClickedFcn = createCallbackFcn(app, @ButtonExportImageClicked, true);
            app.ButtonImport = uiimage(app.UIFigure);
            app.ButtonImport.ImageClickedFcn = createCallbackFcn(app, @ButtonImportImageClicked, true);
            app.ButtonHelp = uiimage(app.UIFigure);
            app.ButtonHelp.ImageClickedFcn = createCallbackFcn(app, @ButtonHelpImageClicked, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Launcher(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end